classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        StatusLabel     matlab.ui.control.Label
        SistemadeArrefecimentoNMOGLabel  matlab.ui.control.Label
        ConectarButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CanChannel % Aqui ficará guardada a sua conexão Vector
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
% Parâmetros (iguais ao seu script)
    hwDevice = 'VN1630A 1';
    hwChannel = 3;
    baudRate = 500000;
    
    % Muda texto para avisar que está tentando
    app.StatusLabel.Text = 'Conectando à Vector...';
    app.StatusLabel.FontColor = 'blue';
    drawnow; % Força atualização da tela
    
    try
        % 1. Verifica se já existe algo e para
        if ~isempty(app.CanChannel) && isvalid(app.CanChannel)
            stop(app.CanChannel);
        end
        
        % 2. Cria a conexão GUARDANDO EM 'app.CanChannel'
        app.CanChannel = canChannel('Vector', hwDevice, hwChannel);
        
        % 3. Configura a velocidade
        configBusSpeed(app.CanChannel, baudRate);
        
        % 4. Inicia
        start(app.CanChannel);
        
        % 5. Sucesso visual
        app.StatusLabel.Text = 'CONECTADO (VN1630A)';
        app.StatusLabel.FontColor = 'green';
        
        % Opcional: Desabilita o botão conectar para não clicar 2x
        app.ConectarButton.Enable = 'off';
        
    catch ME
        % Erro visual
        app.StatusLabel.Text = 'ERRO DE CONEXÃO';
        app.StatusLabel.FontColor = 'red';
        uialert(app.UIFigure, ME.message, 'Erro Vector');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConectarButton = uibutton(app.UIFigure, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.SistemadeArrefecimentoNMOGLabel = uilabel(app.UIFigure);
            app.SistemadeArrefecimentoNMOGLabel.Text = 'Sistema de Arrefecimento- NMOG';
            app.StatusLabel = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end